classdef ForwardKinematics2D < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        GridLayout              matlab.ui.container.GridLayout
        LeftPanel               matlab.ui.container.Panel
        ByTamimLabel            matlab.ui.control.Label
        UITable                 matlab.ui.control.Table
        JointAngle3Slider       matlab.ui.control.Slider
        JointAngle3SliderLabel  matlab.ui.control.Label
        JointAngle2Slider       matlab.ui.control.Slider
        JointAngle2SliderLabel  matlab.ui.control.Label
        JointAngle1Slider       matlab.ui.control.Slider
        JointAngle1SliderLabel  matlab.ui.control.Label
        RightPanel              matlab.ui.container.Panel
        UIAxes2                 matlab.ui.control.UIAxes
        UIAxes                  matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        L1 = 0.3;  % Link 1 length
        L2 = 0.3; % Link 2 length
        L3 = 0.32;  % Link 3 length

        theta1 = 0.2;  % Joint 1 angle
        theta2 = 0.5;  % Joint 2 angle
        theta3 = -0.3; % Joint 3 angle
    end
    
    methods (Access = private)
        
        function plot_kin(app, theta1, theta2)
            plot(app.UIAxes, theta1, theta2); 
        end
        
        function update_table(app)
            a = ["Theta_1" app.theta1; "Theta_2" app.theta2;"Theta_3" app.theta3];
            app.UITable.Data = a; 
        end

        function forward_kinematics(app)
            x = app.L1 * cos(app.theta1) + app.L2 * cos(app.theta1 + app.theta2) + app.L3 * cos(app.theta1 + app.theta2 + app.theta3);
            y = app.L1 * sin(app.theta1) + app.L2 * sin(app.theta1 + app.theta2) + app.L3 * sin(app.theta1 + app.theta2 + app.theta3);
            
            arm_points = [0, app.L1 * cos(app.theta1), app.L1 * cos(app.theta1) + app.L2 * cos(app.theta1 + app.theta2), x;
                          0, app.L1 * sin(app.theta1), app.L1 * sin(app.theta1) + app.L2 * sin(app.theta1 + app.theta2), y];
           
            plot(app.UIAxes,arm_points(1, :), arm_points(2, :), '-o', 'LineWidth', 2, 'MarkerSize', 8);
            xlim(app.UIAxes, [0, 1]);
            ylim(app.UIAxes, [0, 1]);
           

            plot(app.UIAxes2, x, y, 'd', 'MarkerSize', 10, 'MarkerEdgeColor','b',  'MarkerFaceColor',[0.5,0.5,0.5]); 
            xlim(app.UIAxes2, [0, 1]);
            ylim(app.UIAxes2, [0, 1]);
           
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: JointAngle1Slider, JointAngle1Slider
        function joint_angle_1_update(app, event)
            value = app.JointAngle1Slider.Value;
            value = value * pi/180; 
            app.theta1 = value; 
            
            app.update_table();
            app.forward_kinematics(); 
            
        end

        % Value changed function: JointAngle2Slider
        function JointAngle2SliderValueChanged(app, event)
            value = app.JointAngle2Slider.Value;
            value = value * pi/180; 
            app.theta2 = value; 
            
            app.update_table();
            app.forward_kinematics(); 
            
        end

        % Value changed function: JointAngle3Slider
        function JointAngle3SliderValueChanged(app, event)
            value = app.JointAngle3Slider.Value;
            value = value * pi/180; 
            app.theta3 = value; 
            
            app.update_table();
            app.forward_kinematics(); 
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {592, 592};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {258, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.JointAngle1SliderLabel = uilabel(app.LeftPanel);
            app.JointAngle1SliderLabel.Text = 'Joint Angle 1';
            app.JointAngle1Slider = uislider(app.LeftPanel);
            app.JointAngle1Slider.Limits = [0 90];
            app.JointAngle1Slider.ValueChangedFcn = createCallbackFcn(app, @joint_angle_1_update, true);
            app.JointAngle1Slider.ValueChangingFcn = createCallbackFcn(app, @joint_angle_1_update, true);
            app.JointAngle2SliderLabel = uilabel(app.LeftPanel);
            app.JointAngle2SliderLabel.Text = 'Joint Angle 2';
            app.JointAngle2Slider = uislider(app.LeftPanel);
            app.JointAngle2Slider.Limits = [-90 90];
            app.JointAngle2Slider.ValueChangedFcn = createCallbackFcn(app, @JointAngle2SliderValueChanged, true);
            app.JointAngle3SliderLabel = uilabel(app.LeftPanel);
            app.JointAngle3SliderLabel.Text = 'Joint Angle 3';
            app.JointAngle3Slider = uislider(app.LeftPanel);
            app.JointAngle3Slider.Limits = [-90 90];
            app.JointAngle3Slider.ValueChangedFcn = createCallbackFcn(app, @JointAngle3SliderValueChanged, true);
            app.UITable = uitable(app.LeftPanel);
            app.UITable.ColumnName = {'Name'; 'Value'};
            app.UITable.RowName = {};
            app.ByTamimLabel = uilabel(app.LeftPanel);
            app.ByTamimLabel.Text = 'By Tamim ';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Forward Kinematics of 2D Arm')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.RightPanel);
            title(app.UIAxes2, 'End Effector Position')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ForwardKinematics2D

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end